classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        STOPButton                      matlab.ui.control.Button
        wpisaniemaxheight               matlab.ui.control.TextArea
        Entermaxheightdefaultis2mLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        HeightmGauge                    matlab.ui.control.LinearGauge
        HeightmGaugeLabel               matlab.ui.control.Label
        BatteryGauge                    matlab.ui.control.LinearGauge
        BATTERYLabel                    matlab.ui.control.Label
        wpisanietagsize                 matlab.ui.control.TextArea
        Enterthetagsizedefaultis0184mTextAreaLabel  matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
    end

    % tu trzeba okreslic zmienne
    properties (Access = public)
        tagSize = 0.184; % rozmiar tagu
        maxheight = 2;
        stop = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            initialization; %ustalenie wartosci pewnych zmiennych
            r = ryze();
            cameraObj = camera(r);  %do obslugi kamery
            figures;        %wywoluje okienka w ktorych bedzie mapa oraz obraz z kamery
            takeoff(r);     %wzlot drona
            pause(3)        %pauza by się ustabilizował
            [sheight,~] = readHeight(r); %odczyt wysokosci 
            pozdrona(3) = sheight;       %aktualizacja poz drona (wysokosci)
            drawdrone;
            app.BatteryGauge.Value = r.BatteryLevel;
            app.HeightmGauge.Value = pozdrona(3);
            ruch_zrobiony = 0;

            %głowna pętla programu
            while(app.stop < 1)
                wykryto = 0;
                %sprawdzamy czy ma tag przed sobą
                stopklatka;

                %rysuje tag na mapie - jesli ma pozwolenie
                for i = 1:length(isthereatag)
                    if isthereatag(i) == 1 && canidrawtag(i) == 1
                        wykryto = 1;
                        drawtag;
                        canidrawtag(i) = 0;
                        %wyświetlenie na konsoli
                        %event = "tag wykryty";
                        %EditFieldValueChanging(app , event);
                    end
                end

                %przemiezczenie drona
             for i = 1:length(isthereatag)
                if isthereatag(i) == 1 && canigo(i) == 1
                    wykryto = 1;
                    %obl ruch, wykresla poz tagow i drona, ruch drona
                    %sprawdza czy może się ruszyć
                    if abs(tagwzglkamery(i,1))>0.2 || tagwzglkamery(i,2)-0.8>0.2 || abs(tagwzglkamery(i,3))> 0.2
                        move(r,[tagwzglkamery(i,2)-0.8 tagwzglkamery(i,1) tagwzglkamery(i,3)]);
                        pause(0.5);
                        canigo(i) = 0;
                        amiontag = 1;
                    
                        %obliczenie bezwzgl pozycji drona
                        theta = readOrientation(r); %odczyt orientacji
                        r = [cos(-theta(1)) -sin(-theta(1)); sin(-theta(1)) cos(-theta(1))]; %rot matrix
                        dronpoint = [tagwzglkamery(i,1) tagwzglkamery(i,2)-0.8]'; %ile się przemieści w x i y
                        dronrotpoint = r*dronpoint;
        
                        %aktualizacja pozycji drona
                        pozdrona(1) = pozdrona(1)+dronrotpoint(1);
                        pozdrona(2) = pozdrona(2)+dronrotpoint(2);
                        pozdrona(3) = pozdrona(3)+tagwzglkamery(i,3);
                        app.HeightmGauge.Value = pozdrona(3);
                        %rysuje poz drona
                        drawdrone;
                        stopklatka;
                        %rysuje uaktualnioną poz taga; z jakiegoś powodu nie chce rysowac :/
                        drawtag;
                        %akcja
                        pause(1)
                        
                        %--------Akcja specjalna---------
                        %try
                        %   doanaction;
                        %catch
                            %rysuowanie warningow
                        %   warning('nie udalo sie zrobic akcji');
                         %  event = "Nie udalo sie zrobic akcji";
                          % EditFieldValueChanging(app , event);
                        %end
    
                        %tutaj musi zbadać tag i policzyć swoją pozycję
                        %względem niego (jeśli robi flip
    
    
                   else %nie może podlecieć więc robi warning że tag jest za blisko
                        canigo(i) = 0;
                        amiontag = 1;
                        %akcja
                        pause(1)
    
                        %event = "Nie udalo sie zrobic akcji , tag jest zbyt blisko";
                        %EditFieldValueChanging(app , event);
                        
                        %--------Akcja specjalna---------
                        %try
                        %   doanaction;
                        %catch
                        %rysuowanie warningow
                        %   warning('nie udalo sie zrobic akcji');
                         %  event = "Nie udalo sie zrobic akcji";
                          % EditFieldValueChanging(app , event);
                        %end
    
                        %tutaj musi zbadać tag i policzyć swoją pozycję
                        %względem niego (jeśli robi flip


                   end

                   %powrot do pierwotnej pozycji
                   if abs(pozdrona(1))>0.2 || abs(pozdrona(2))>0.2 % sheight pzdrona - przesuw = sheight
                       move(r,[-pozdrona(2) -pozdrona(1) -pozdrona(3) + sheight]); % przesuw = pozdrona - sheight
                       pozdrona(1:3) = [0 0 sheight];
                       app.HeightmGauge.Value = pozdrona(3);
                       drawdrone;
                   end

                end
             end

                if wykryto == 0
                    for tfi = 1:8
                        turn(r,pi/4)
                        pause(0.5)
                        stopklatka;
                        for i = 1:length(isthereatag)

                        if all([isthereatag(i) , canidrawtag(i)] == 1)
                            wykryto = 1;
                            drawtag;
                            canidrawtag(i) = 0;
                        end
                        pause(0.5)
                        if all([isthereatag(i) , canigo(i)] == 1)
                            pause(0.5)
                            if abs(tagwzglkamery(i,1))>0.2 || tagwzglkamery(i,2)-0.8>0.2 || abs(tagwzglkamery(i,3))> 0.2
                                move(r,[tagwzglkamery(i,2)-0.8 tagwzglkamery(i,1) tagwzglkamery(i,3)]);
                                pause(0.5);
                                canigo(i) = 0;
                                amiontag = 1;
                                        
                                %obliczenie bezwzgl pozycji drona
                                theta = readOrientation(r); %odczyt orientacji
                                r = [cos(-theta(1)) -sin(-theta(1)); sin(-theta(1)) cos(-theta(1))]; %rot matrix
                                dronpoint = [tagwzglkamery(i,1) tagwzglkamery(i,2)-0.8]'; %ile się przemieści w x i y
                                dronrotpoint = r*dronpoint;
                            
                               %aktualizacja pozycji drona
                               pozdrona(1) = pozdrona(1)+dronrotpoint(1);
                               pozdrona(2) = pozdrona(2)+dronrotpoint(2);
                               pozdrona(3) = pozdrona(3)+tagwzglkamery(i,3);
                               app.HeightmGauge.Value = pozdrona(3);
                               %rysuje poz drona
                               drawdrone;
                               stopklatka;
                               %rysuje uaktualnioną poz taga; z jakiegoś powodu nie chce rysowac :/
                               drawtag;
                               %akcja
                               pause(1)
                                            
                               %--------Akcja specjalna---------
                               %try
                               %   doanaction;
                               %catch
                               %rysuowanie warningow
                               %   warning('nie udalo sie zrobic akcji');
                                %  event = "Nie udalo sie zrobic akcji";
                                 % EditFieldValueChanging(app , event);
                               %end
                        
                               %tutaj musi zbadać tag i policzyć swoją pozycję
                               %względem niego (jeśli robi flip
                                               
                           else %nie może podlecieć więc robi warning że tag jest za blisko
                               canigo(i) = 0;
                               amiontag = 1;
                               %akcja
                               pause(1)
                               %event = "Nie udalo sie zrobic akcji , tag jest zbyt blisko";
                               %EditFieldValueChanging(app , event);
                                            
                               %--------Akcja specjalna---------
                                %try
                               %   doanaction;
                               %catch
                               %rysuowanie warningow
                               %   warning('nie udalo sie zrobic akcji');
                                %  event = "Nie udalo sie zrobic akcji";
                                 % EditFieldValueChanging(app , event);
                               %end
                        
                               %tutaj musi zbadać tag i policzyć swoją pozycję
                               %względem niego (jeśli robi flip
                            end
                            %powrot do pierwotnej pozycji
                            if abs(pozdrona(1))>0.2 || abs(pozdrona(2))>0.2 % sheight pzdrona - przesuw = sheight
                               move(r,[-pozdrona(2) -pozdrona(1) -pozdrona(3) + sheight]); % przesuw = pozdrona - sheight
                               pozdrona(1:3) = [0 0 sheight];
                               app.HeightmGauge.Value = pozdrona(3);
                               drawdrone;
                            end
                        end
                        end
                        if wykryto == 1
                            break
                        end
                    end
                end
                

                %Jeśli nie przekroczył maksymalnej wysokości to leci do
                %góry
                [sheight,~] = readHeight(r);
                if sheight + 0.5<app.maxheight
                   moveup(r,'Distance',0.5);
                   pause(1);
                   [sheight,~] = readHeight(r);
                   pozdrona(3) = sheight;
                   app.HeightmGauge.Value = pozdrona(3);
                   drawdrone;
                   pause(1);
                else
                   app.stop = 1;
                end   
            end
            %Koniec
            land(r);
            clear(r);
        end

        % Value changed function: wpisanietagsize
        function wpisanietagsizeValueChanged(app, event)
            app.tagSize = str2double(app.wpisanietagsize.Value);
        end

        % Value changed function: wpisaniemaxheight
        function wpisaniemaxheightValueChanged(app, event)
            app.wpisaniemaxheight = str2double(app.wpisaniemaxheight.Value);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop = 1;
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            changingValue = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Enterthetagsizedefaultis0184mTextAreaLabel = uilabel(app.UIFigure);
            app.Enterthetagsizedefaultis0184mTextAreaLabel.Text = 'Enter the tag size (default is 0.184) [m]';
            app.wpisanietagsize = uitextarea(app.UIFigure);
            app.wpisanietagsize.ValueChangedFcn = createCallbackFcn(app, @wpisanietagsizeValueChanged, true);
            app.BATTERYLabel = uilabel(app.UIFigure);
            app.BATTERYLabel.Text = 'Battery[%]';
            app.BatteryGauge = uigauge(app.UIFigure, 'linear');
            app.HeightmGaugeLabel = uilabel(app.UIFigure);
            app.HeightmGaugeLabel.Text = 'Height [m]';
            app.HeightmGauge = uigauge(app.UIFigure, 'linear');
            app.HeightmGauge.Limits = [0 10];
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.Entermaxheightdefaultis2mLabel = uilabel(app.UIFigure);
            app.Entermaxheightdefaultis2mLabel.Text = 'Enter max height (default is 2) [m]';
            app.wpisaniemaxheight = uitextarea(app.UIFigure);
            app.wpisaniemaxheight.ValueChangedFcn = createCallbackFcn(app, @wpisaniemaxheightValueChanged, true);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end