classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        STOPButton                      matlab.ui.control.Button
        wpisaniemaxheight               matlab.ui.control.TextArea
        Entermaxheightdefaultis2mLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        HeightmGauge                    matlab.ui.control.LinearGauge
        HeightmGaugeLabel               matlab.ui.control.Label
        BatteryGauge                    matlab.ui.control.LinearGauge
        BATTERYLabel                    matlab.ui.control.Label
        wpisanietagsize                 matlab.ui.control.TextArea
        Enterthetagsizedefaultis0184mTextAreaLabel  matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
    end

    % tu trzeba okreslic zmienne
    properties (Access = public)
        tagSize = 0.184; % rozmiar tagu
        maxheight = 2;
        stop = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
initialization; %ustalenie wartosci pewnych zmiennych
r = ryze();
cameraObj = camera(r);  %do obslugi kamery
figures;        %wywoluje okienka w ktorych bedzie mapa oraz obraz z kamery
takeoff(r);     %wzlot drona
pause(4)        %pauza by się ustabilizował
[sheight,~] = readHeight(r); %odczyt wysokosci 
pozdrona(3) = sheight;       %aktualizacja poz drona (wysokosci)
drawdrone;
app.BatteryGauge.Value = r.BatteryLevel;
app.HeightmGauge.Value = pozdrona(3);

while(app.stop<1)   %glowna petla programu
    %obraz kamery/detekcja tagów
    stopklatka;
    %rysuje tag na mapie - jesli ma pozwolenie
    for i = 1:length(isthereatag)
        if isthereatag(i) == 1 && canidrawtag(i) == 1
            drawtag;
            canidrawtag(i) = 0;
            %value = "Tag wykryty";
        end
    end

    for i = 1:length(isthereatag)
        if isthereatag(i) == 1 && canigo(i) == 1
            %obl ruch, wykresla poz tagow i drona, ruch drona
            %sprawdza czy może się ruszyć
            if abs(tagwzglkamery(i,1))>0.2 || tagwzglkamery(i,2)-0.8>0.2 || abs(tagwzglkamery(i,3))> 0.2
                move(r,[tagwzglkamery(i,2)-0.8 tagwzglkamery(i,1) tagwzglkamery(i,3)]);
                pause(0.5);
                canigo(i) = 0;
                amiontag = 1;
                
                %obliczenie bezwzgl pozycji drona
                theta = readOrientation(r); %odczyt orientacji
                r = [cos(-theta(1)) -sin(-theta(1)); sin(-theta(1)) cos(-theta(1))]; %rot matrix
                dronpoint = [tagwzglkamery(i,1) tagwzglkamery(i,2)-0.8]'; %ile się przemieści w x i y
                dronrotpoint = r*dronpoint;

                %aktualizacja pozycji drona
                pozdrona(1) = pozdrona(1)+dronrotpoint(1);
                pozdrona(2) = pozdrona(2)+dronrotpoint(2);
                pozdrona(3) = pozdrona(3)+tagwzglkamery(i,3);
                app.HeightmGauge.Value = pozdrona(3);
                %rysuje poz drona
                drawdrone;
                stopklatka;
                %rysuje uaktualnioną poz taga; z jakiegoś powodu nie chce rysowac :/
                drawtag;
                %akcja
                pause(1)
                try
                    doanaction;
                catch
                    warning('nie udalo sie zrobic akcji');
                    %value = "nie udalo sie zrobic akcji";
                end
            else
                canigo(i) = 0;
                amiontag = 1;
                stopklatka;
                %rysuje uaktualnioną poz taga; z jakiegoś powodu nie chce rysowac :/
                drawtag;
                %akcja
                pause(1)
                try
                    doanaction;
                catch
                    warning('nie udalo sie zrobic akcji');
                    %value = "nie udalo sie zrobic akcji";
                end
            end
            
        end
    end
    %obliczyc o ile dron ma sie poruszyc by dotrzec do taga
    %po dotarciu do taga jeszcze raz wykreslic jego polozenie na mapie
    %oraz sprawdzic czy nie musi jeszcze sie jakos do niego zblizyc.
    %=======done tyle ze trzeba tam jeszcze dodac wykonanieakcji(fikolek)

    %Teraz ma sie obracac przed tagiem w poszukiwaniu innych tagow.
    %Jesli nie znajdzie to wraca na poczatek i znowu sie obraca po tagi
    
    if amiontag == 1
        for tfi = 1:3
            turn(r,pi/2)
            pause(0.5)
            stopklatka;
            for i = 1:length(isthereatag)
                if isthereatag(i) == 1 && canidrawtag(i) == 1
                    drawtag;
                    canidrawtag(i) = 0;
                end
            end
            for i = 1:length(isthereatag)
                if isthereatag(i) == 1 && canigo(i) == 1
                    movetotag;
                    stopklatka;
                    %rysuje uaktualnioną poz taga;
                    drawtag;
                    %akcja
                    pause(1)
                    try
                        doanaction;
                    catch
                        warning('nie udalo sie zrobic akcji');
                        %value = "nie udalo sie zrobic akcji";
                    end
                    canigo(i) = 0;
                    tfi = 1;
                end
            end
        end
        %powrot do pierwotnej orientacji
        theta = readOrientation(r); %czyta kąt theta
        turn(r,-theta(1));
        pause(1);

        if abs(pozdrona(1))>0.2 || abs(pozdrona(2))>0.2 % sheight pzdrona - przesuw = sheight
            move(r,[-pozdrona(2) -pozdrona(1) pozdrona(3) - sheight]);                        % przesuw = pozdrona - sheight
            pozdrona(1:3) = [0 0 sheight];
            app.HeightmGauge.Value = pozdrona(3);
            drawdrone;
        end
        amiontag = 0;
        turn(r,prevangle);
    end

    %to teraz obrot
    turn(r,pi/2);
    [sheight,~] = readHeight(r);
    theta = readOrientation(r); %czyta kąt theta
    prevangle = theta(1);
    pause(0.5);
    if abs(theta(1))<0.6  
        if sheight + 0.5<app.maxheight
            moveup(r,'Distance',0.5);
            pause(1);
            [sheight,~] = readHeight(r);
            pozdrona(3) = sheight;
            app.HeightmGauge.Value = pozdrona(3);
            drawdrone;
            pause(0.5)
        elseif app.maxheight - sheight > 0.2
            moveup(r,'Distance',app.maxheight - sheight);
            pause(1);
            pozdrona(2) = sheight;
            drawdrone;
            pause(0.5)
        else
            app.stop = 1;
        end
    end
end

land(r);
%value = "Lądowanie";
clear(r);
        end

        % Value changed function: wpisanietagsize
        function wpisanietagsizeValueChanged(app, event)
            app.tagSize = str2double(app.wpisanietagsize.Value);
        end

        % Value changed function: wpisaniemaxheight
        function wpisaniemaxheightValueChanged(app, event)
            app.wpisaniemaxheight = str2double(app.wpisaniemaxheight.Value);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop = 1;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = str(app.EditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Enterthetagsizedefaultis0184mTextAreaLabel = uilabel(app.UIFigure);
            app.Enterthetagsizedefaultis0184mTextAreaLabel.Text = 'Enter the tag size (default is 0.184) [m]';
            app.wpisanietagsize = uitextarea(app.UIFigure);
            app.wpisanietagsize.ValueChangedFcn = createCallbackFcn(app, @wpisanietagsizeValueChanged, true);
            app.BATTERYLabel = uilabel(app.UIFigure);
            app.BATTERYLabel.Text = 'Battery[%]';
            app.BatteryGauge = uigauge(app.UIFigure, 'linear');
            app.HeightmGaugeLabel = uilabel(app.UIFigure);
            app.HeightmGaugeLabel.Text = 'Height [m]';
            app.HeightmGauge = uigauge(app.UIFigure, 'linear');
            app.HeightmGauge.Limits = [0 10];
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Entermaxheightdefaultis2mLabel = uilabel(app.UIFigure);
            app.Entermaxheightdefaultis2mLabel.Text = 'Enter max height (default is 2) [m]';
            app.wpisaniemaxheight = uitextarea(app.UIFigure);
            app.wpisaniemaxheight.ValueChangedFcn = createCallbackFcn(app, @wpisaniemaxheightValueChanged, true);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end