classdef nowa14_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        STOPButton                      matlab.ui.control.Button
        wpisaniemaxheight               matlab.ui.control.TextArea
        Entermaxheightdefaultis2mLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        HeightmGauge                    matlab.ui.control.LinearGauge
        HeightmGaugeLabel               matlab.ui.control.Label
        BatteryGauge                    matlab.ui.control.LinearGauge
        BATTERYLabel                    matlab.ui.control.Label
        wpisanietagsize                 matlab.ui.control.TextArea
        Enterthetagsizedefaultis0184mTextAreaLabel  matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
    end

    % tu trzeba okreslic zmienne
    properties (Access = public)
        tagSize = 0.184; % rozmiar tagu
        maxheight = 2;
        stop = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
                app.HeightmGauge.Value = 10;
                tagSize = app.tagSize;
                heightIncrease = 0.5;
                actionDistance = 0.4;
    
                %łączenie się z dronem
                r = ryze();
                c = camera(r);
    
                %start
                takeoff(r);
                pause(1);
                [height, ~] = readHeight(r);
                

            while(app.stop = 0)
                for int = 1:7
                %img = imread("drone2.jpeg");
                img = snapshot(c);
                focalLength    = [4*720/3 4*960/4];
                principalPoint = [480 360];
                imageSize      = [720 960];
                intrinsics = cameraIntrinsics(focalLength , principalPoint , imageSize);
                img = undistortImage(img,intrinsics,OutputView="same");
                
                [id , loc , pose] = readAprilTag(img , "tag36h11" , intrinsics , tagSize);
                
                worldPoints = [0 0 0; tagSize/2 0 0; 0 tagSize/2 0; 0 0 tagSize/2];
                for i = 1 : length(pose)
                    % Get image coordinates for axes.
                    imagePoints = world2img(worldPoints , pose(i) , intrinsics);
                    % Draw colored axes.
                    img = insertShape(img , Line=[imagePoints(1,:) imagePoints(2,:); ...
                        imagePoints(1,:) imagePoints(3,:); imagePoints(1,:) imagePoints(4,:)], ...
                        Color=["red","green","blue"],LineWidth=7);
                    img = insertText(img,loc(1,:,i),id(i),BoxOpacity=1,FontSize=25);
                end
                
                %sprawdza czy wykrył znacznik
                if isempty(pose) == 0
                    %odczyt przesunięcia normalnie
                    l1x = pose.Translation(1);
                    l1y = -pose.Translation(2);
                    l1z = pose.Translation(3);
        
                    %wyświetlanie zdjęcia
                    figure
                    imshow(img)
                    
                    %tutaj powinien wykonąć ruch pionowy 
                    moveup(r, -pose.Translation(2));
                    
                    %robi nowe zdjęcie i odczytuje pozycje jeszcze raz
                    %img = imread("drone2.jpeg");
                    img = snapshot(c);
                    img = undistortImage(img,intrinsics,OutputView="same");
        
                    [id , loc , pose] = readAprilTag(img , "tag36h11" , intrinsics , tagSize);
                    
                    worldPoints = [0 0 0; tagSize/2 0 0; 0 tagSize/2 0; 0 0 tagSize/2];
                    for i = 1 : length(pose)
                        % Get image coordinates for axes.
                        imagePoints = world2img(worldPoints , pose(i) , intrinsics);
                        % Draw colored axes.
                        img = insertShape(img , Line=[imagePoints(1,:) imagePoints(2,:); ...
                            imagePoints(1,:) imagePoints(3,:); imagePoints(1,:) imagePoints(4,:)], ...
                            Color=["red","green","blue"],LineWidth=7);
                        img = insertText(img,loc(1,:,i),id(i),BoxOpacity=1,FontSize=25);
                    end
                    
                    %odczyt przesunięcia normalnie
                    l1x = pose.Translation(1);
                    l1y = -pose.Translation(2);
                    l1z = pose.Translation(3);
        
                    %wyświetlanie zdjęcia
                    figure
                    imshow(img)
                    
                    %odczyty kątowe i obliczanie rotacji
                    l2x = l1x + pose.R(1, 3);
                    l2y = l1y + pose.R(2, 3);
                    l2z = l1z + pose.R(3, 3);
                    l1 = [l1x, l1y, l1z];
                    l2 = [l2x, l2y, l2z];
                    c1 = [0, 0, 0];
                    c2 = [0, 0, 1];
                    v1 = l1 - l2;
                    v2 = c1 - c2;
                    Theta = atan2(norm(cross(v1, v2)), dot(v1, v2));
                    if pose.R(1, 3) < 0
                        Theta = -Theta;
                    end
                    rad2deg(Theta)

                    %tutaj turna o Theta powinien wykonać
                    turn(r, Theta);
                    
                    %obliczanie przesunięcia po obrocie
                    [theta,rho,z] = cart2pol(l1x,l1z,l1y);
                    theta = theta + Theta;
                    [x,z,~] = pol2cart(theta,rho,z);
                    
                    %tutaj powinien się przesunąć w osiach x i y czyli do przodu i w bok 
                    pause(1);
                    move(r, [z - actionDistance, x, 0]);
                    
                    %wykonanie flipa
                    pause(1);
                    flip(r, "back");
                    pause(1);
    
                    %powrót na pozycje
                    move(r, [-z + actionDistance, -x, 0]);
                    pause(1);
                    turn(r, -Theta);
                    pause(1);
                end
    
                %obrót w poszukiwaniu nowego taga (o 45 stopni)
                turn(r, pi/4);
                pause(1);

                end

            %końcowy obrót przed podleceniem do góry
            turn(r, pi/4);
            pause(1);

            %podlecenie do góry
            moveup(r, heightIncrease); 
            pause(1);

            %ponowny odczyt wysokości
            [height, ~] = readHeight(r);

            end

            land(r);

        end

        % Value changed function: wpisanietagsize
        function wpisanietagsizeValueChanged(app, event)
            app.tagSize = str2double(app.wpisanietagsize.Value);
        end

        % Value changed function: wpisaniemaxheight
        function wpisaniemaxheightValueChanged(app, event)
            app.maxheight = str2double(app.wpisaniemaxheight.Value);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Enterthetagsizedefaultis0184mTextAreaLabel = uilabel(app.UIFigure);
            app.Enterthetagsizedefaultis0184mTextAreaLabel.Text = 'Enter the tag size (default is 0.184) [m]';
            app.wpisanietagsize = uitextarea(app.UIFigure);
            app.wpisanietagsize.ValueChangedFcn = createCallbackFcn(app, @wpisanietagsizeValueChanged, true);
            app.BATTERYLabel = uilabel(app.UIFigure);
            app.BATTERYLabel.Text = 'Battery[%]';
            app.BatteryGauge = uigauge(app.UIFigure, 'linear');
            app.HeightmGaugeLabel = uilabel(app.UIFigure);
            app.HeightmGaugeLabel.Text = 'Height [m]';
            app.HeightmGauge = uigauge(app.UIFigure, 'linear');
            app.HeightmGauge.Limits = [0 10];
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Entermaxheightdefaultis2mLabel = uilabel(app.UIFigure);
            app.Entermaxheightdefaultis2mLabel.Text = 'Enter max height (default is 2) [m]';
            app.wpisaniemaxheight = uitextarea(app.UIFigure);
            app.wpisaniemaxheight.ValueChangedFcn = createCallbackFcn(app, @wpisaniemaxheightValueChanged, true);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nowa14_01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end